classdef ESP32 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        MOTORSTOPButton   matlab.ui.control.Button
        MOTORSTARTButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        CompPort="COM7"
        BaudRate=115200
        SerialObj 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialization

            app.CompPort="COM7";
            app.BaudRate=115200;

            %try-catch statement - to make sure connection is correctly set
            %up

            try
                app.SerialObj = serialport(app.CompPort,app.BaudRate);
                disp("serial Port initialized correctly");

            catch
                disp("Serial Port not initialized correctly");
            end

        end

        % Button pushed function: MOTORSTARTButton
        function StartButtonPushed(app, event)
            try
                if isempty(app.SerialObj) || ~isvalid(app.SerialObj)
                    app.SerialObj=serialport(app.CompPort,app.BaurdRate);

                end

                %Communication with ESP - using 1 and 0
                %1 to start motor 
                %0 to stop motor

                write(app.SerialObj,'1',"char");
                disp('Sent 1 to Serial to start the motor');
                
            catch ME
                uialert(app.UIFigure,ME.message,'Serial Connection Error');

            end
        end

        % Button pushed function: MOTORSTOPButton
        function StopButtonPushed(app, event)
            try
                if isempty(app.SerialObj) || ~isvalid(app.SerialObj)
                    app.SerialObj=serialport(app.CompPort,app.BaurdRate);

                end

                %Communication with ESP - using 1 and 0
                %1 to start motor 
                %0 to stop motor

                write(app.SerialObj,'0',"char");
                disp('Sent 0 to Serial to start the motor');

            catch ME
                uialert(app.UIFigure,ME.message,'Serial Connection Error');

            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MOTORSTARTButton = uibutton(app.UIFigure, 'push');
            app.MOTORSTARTButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.MOTORSTARTButton.Text = 'MOTOR START';
            app.MOTORSTOPButton = uibutton(app.UIFigure, 'push');
            app.MOTORSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.MOTORSTOPButton.Text = 'MOTOR STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ESP32

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end